classdef Tethered_Lean_Release_Feedback < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        Menu_2                        matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        MarkerSwitch                  matlab.ui.control.Switch
        MarkerSwitchLabel             matlab.ui.control.Label
        WeightkgSpinnerLabel_3        matlab.ui.control.Label
        EditField_3                   matlab.ui.control.EditField
        MarkerPropTypeDropDown        matlab.ui.control.DropDown
        MarkerPropTypeDropDownLabel   matlab.ui.control.Label
        MarkerStreamInfoLabel         matlab.ui.control.Label
        CalibWeightkgEditField        matlab.ui.control.NumericEditField
        CalibWeightkgEditFieldLabel   matlab.ui.control.Label
        StreamControlsLabel           matlab.ui.control.Label
        CalibrateButton               matlab.ui.control.Button
        SensorStreamInfoLabel         matlab.ui.control.Label
        CoMTypeDropDown               matlab.ui.control.DropDown
        CoMTypeDropDownLabel          matlab.ui.control.Label
        PullTypeDropDown              matlab.ui.control.DropDown
        PullTypeDropDownLabel         matlab.ui.control.Label
        TetherHeightcmSpinner         matlab.ui.control.Spinner
        TetherHeightcmSpinnerLabel    matlab.ui.control.Label
        CoMCoefficientSpinner         matlab.ui.control.Spinner
        CoMCoefficientSpinnerLabel    matlab.ui.control.Label
        TerminateButton               matlab.ui.control.Button
        StartButton                   matlab.ui.control.StateButton
        InitiateButton                matlab.ui.control.Button
        WeightkgSpinnerLabel_2        matlab.ui.control.Label
        AuxiliaryInfoLabel            matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        SensorPropTypeDropDown        matlab.ui.control.DropDown
        SensorPropTypeDropDownLabel   matlab.ui.control.Label
        TextArea2                     matlab.ui.control.TextArea
        LogLabel                      matlab.ui.control.Label
        HeightcmSpinner               matlab.ui.control.Spinner
        HeightcmSpinnerLabel          matlab.ui.control.Label
        WeightkgSpinner               matlab.ui.control.Spinner
        WeightkgSpinnerLabel          matlab.ui.control.Label
        PatientInfoLabel              matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        ChannelSelectEditField        matlab.ui.control.NumericEditField
        ChannelSelectEditFieldLabel   matlab.ui.control.Label
        TargetAngledegEditField       matlab.ui.control.NumericEditField
        TargetAngledegEditFieldLabel  matlab.ui.control.Label
        EstimationTypeDropDown        matlab.ui.control.DropDown
        EstimationTypeDropDownLabel   matlab.ui.control.Label
        RawReadingsTypeDropDown       matlab.ui.control.DropDown
        RawReadingsTypeDropDownLabel  matlab.ui.control.Label
        FileTypeDropDown              matlab.ui.control.DropDown
        FileTypeDropDownLabel         matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        SavePathEditField             matlab.ui.control.EditField
        SavePathEditFieldLabel        matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        RawReadingsTextArea           matlab.ui.control.TextArea
        RawReadingsTextAreaLabel      matlab.ui.control.Label
        ReadytoReleaseLamp            matlab.ui.control.Lamp
        ReadytoReleaseLampLabel       matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        ClearMenu                     matlab.ui.container.Menu
        ScrollMenu                    matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        ClearMenu_2                   matlab.ui.container.Menu
        ScrollMenu_2                  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % private properties
    properties (Access = private)
        lib; % LSL library handle instance   
        stream_infos_sensor; % stream infos of sensor
        stream_type_sensor; % stream type of sensor
        stream_infos_marker; % stream infos of marker
        stream_type_marker; % stream type of marker (should be marker by default)
        sensorStream_num = 1; % select the 1st sensor stream by default
        markerStream_num = 1; % select the 1st marker stream by default

        inlet_sensor; % LSL sensor inlet
        inlet_marker = false; % LSL marker inlet

        PropType_sensor = 'type'; % stream property type, default 'source_id' 'name'
        PropValue_sensor = 'EEG'; % stream property value, default '16100740' for ActiChamp (DESKTOP-D8SQ1D9) 'MyAudioStream''Keyboard'
        Channel_num = 40; % the No. of 1 specific channel to be displayed and stored, if changed during process, saved data will change.
        max_channel_count = 40; % number of all avaliable channel of the stream, will change according to the actual stream channels
        markerOn = true; % marker stream retrieve is by defult on
        PropType_marker = 'name';
        PropValue_marker = 'LiveAmpSN-102801-1200-STETriggerIn';
        
        txt_log = ""; % text to be displayed
        
        PullType = 'chunk'; 
        time_out = 0.1;
        data_vec = -1; % data vector pulled from the sensor inlet
        data_vec_F = []; % formatted data vector, should be a mxn matrix, n is the ts length, m is the channel number
        data_vec_selected = []; % selected formatted data vector, used for force/angle calculation and display
        marker_flag = ''; % string pulled from the marker inlet
        Pressed = false;
        ts_start = -1;
        ts_sensor = -1; % corresponding time stamp of sensor data, in sec
        ts_marker = -1;
        ts_sensor_r = -1 % relative time stamp of sensor data, origin at start of stream, in sec
        ts_marker_r = -1
        sampling_rate = 500; % sampling rate by default 500Hz
        
        
        rawReadingsType = '% Body Weight'; % by default in percentage
        EstimationType = 'Lean Angle';
        CoMType = 'Male Avg';
        CoMCoeff = 0.65; % default CoM Height to Height Ratio
        mass = 60; % default patient mass = 60 kg
        patient_height = 170; % default patient height = 170 cm
        tether_height = 150; % default 150 cm
        grav_acc = 9.80665; % gravitational acceleration constant m/s^2
        offset = 0; % offset for the measured tether force or the calibration weight in N

        force = 0; % force readings from the sensor
        lean_angle = []; % estimated lean angle
        lean_torque = []; % estimated torque at the pivot point (anckle)
        
        % table for storing data:
        TT_all = table(-1, 0, 0, 0); % starting as an empty timetable -> ts, data_vec_F, lean_angle, lean_torque
        TT_marker = table(-1); 
%         TS_raw = timeseries([], [], 'Name', 'Raw Data'); % timeseries for the raw data
%         TS_angle = timeseries([], [], 'Name', 'Estimated Angle'); % timeseries for the angle
%         TS_torque = timeseries([], [], 'Name', 'Estimated Torque'); % timeseries for the torque
        target_lean_angle = 0;
        target_lean_torque = 0;
        T_yline

        tolerance = 0.5;

        AL_1; % animated line for UIAxes1
        AL_2; % animated line for UIAxes2
        WindowSize = 5; % 5 sec window size
        ifScroll_1 = true;
        ifScroll_2 = true;
        yPad = 0.05; % padding ratio of the display ylimits

        ifStop = false;
        flag_1stDisp = false; % for only refresh log the 1st time with new log info during a stream
        
    end
    
    % private methods
    methods (Access = private)

        function FormatData(app)
            % Format Data, only changed data type, does not convert data
            switch class(app.data_vec)
                case 'cell'
                    app.data_vec_F = str2double(app.data_vec{1}); % Keyboard Inputs
                case 'double'
                    app.data_vec_F = app.data_vec;
                    app.RawReadingsTextArea.Value = num2str(app.data_vec_F(1));
                case 'char'
                    app.data_vec_F = str2double(app.data_vec);
                    app.RawReadingsTextArea.Value = app.data_vec_F(:, 1); 
                otherwise
                    app.data_vec_F = double(app.data_vec);
                    app.RawReadingsTextArea.Value = num2str(app.data_vec_F(1)); 
            end

            % the resulted data_vec_F should be in uV, so to display in
            % mV or V, we should devide data_vec_F by 1e3 or 1e6
        end

        function SelectChannel(app)
            % Select Channel to plot/display for multiple-channel data -> EEG 40
            % Channel No. 8
            % All channels will be saved
            if app.Channel_num > size(app.data_vec_F, 1)
                app.Channel_num = size(app.data_vec_F, 1); % preserve the maximum channel
                % update text log
                if ~app.flag_1stDisp
                    app.txt_log = [app.txt_log; "Invalid Channel Number, used the maximum Channel Number avaliable."];
                    app.TextArea2.Value = app.txt_log;
                    app.flag_1stDisp = true;
                end
                % update ChannelNum
                app.ChannelSelectEditField.Value = app.Channel_num;
            end
        end

        function CalculateForce(app)
            % According to the Force Sensor (LSB302) and Signal Conditioner
            % (IAA100) Settings, the conversion is around 30lbs/V
            if isequal(class(app.data_vec_F), 'double')
                app.force = (app.data_vec_F / 1e6) * 30 * 0.453592 * app.grav_acc; % uV to V to lbs to kg to N
                app.force = app.force + mean(app.offset, 'all'); % adds offset to the raw force.
            else
                app.force = -1 * ones(size(app.data_vec_F));
            end
        end
        
        function CalculateAngle(app)
            % calculates the angle from the derived force
            m = app.mass;
            H = app.patient_height / 100; % convert to meters
            g = app.grav_acc;
            alpha = app.CoMCoeff;
            F = app.force;
            h = app.tether_height / 100; % convert to meters

            theta = abs(asin((F * h) / (m * g * H * alpha))); % in radians
            app.lean_angle = theta / pi * 180; % in degrees
        end

        function CalculateTorque(app)
            % calculate torque at the subject's anckle from the derived
            % force
            F = app.force;
            h = app.tether_height;

            app.lean_torque = F * h;
        end

        function DisplayData(app)
            % updating the plots and other display stuff
            % format time stamps according to the start of recording
            app.ts_sensor_r = app.ts_sensor - app.ts_start; % place holder
            app.ts_marker_r = app.ts_marker - app.ts_start;

            % plot
            % the resulted data_vec_F should be in uV, so to display in
            % mV or V, we should devide data_vec_F by 1e3 or 1e6

            % only the selected channel (selected by app.channel_num) is
            % displayed
            switch app.rawReadingsType
                case 'V'
                    addpoints(app.AL_1, app.ts_sensor_r, abs(app.data_vec_F(app.Channel_num, :)) / 1e6);
                case 'mV'
                    addpoints(app.AL_1, app.ts_sensor_r, abs(app.data_vec_F(app.Channel_num, :)) / 1e3);
                case 'N'
                    addpoints(app.AL_1, app.ts_sensor_r, abs(app.force(app.Channel_num, :)));
                otherwise % percent body weight
                    addpoints(app.AL_1, app.ts_sensor_r, abs(app.force(app.Channel_num, :) / (app.mass * app.grav_acc)) * 100); % F / mg
            end
            switch app.EstimationType
                case 'Lean Angle'
%                     angle = app.lean_angle
                    addpoints(app.AL_2, app.ts_sensor_r, app.lean_angle(app.Channel_num, :));
                case 'Lean Torque'                       
                    addpoints(app.AL_2, app.ts_sensor_r, app.lean_torque(app.Channel_num, :));
            end
            if app.ts_marker ~= -1 
                if ~isempty(app.ts_marker)
                    app.Pressed = true; % contains(app.marker_flag{1}, 'pressed');
                    if app.Pressed
                        color_str = '#D95319';
                    else
                        color_str = 'black';
                    end
                        
                    xline(app.UIAxes, app.ts_marker_r, "Color", color_str)
                    xline(app.UIAxes2, app.ts_marker_r, "Color", color_str)
                end
            end
            % Update x-axis and y-axis limits for the rolling window and
            % the displayed value range
            if app.ifScroll_1
                xlim(app.UIAxes, [max(app.ts_sensor_r(end) - app.WindowSize, 0), app.ts_sensor_r(end)]);
%                 [~, y_data] = getpoints(app.AL_1);
%                 y_data_disp = y_data(max(end - (app.WindowSize * app.sampling_rate), 1) : end);
%                 ylim(app.UIAxes, [min(y_data_disp) * (1 - app.yPad), max(y_data_disp) * (1 + app.yPad)]);
            else
                [t, ~] = getpoints(app.AL_1);
                xlim(app.UIAxes, [min(t), app.ts_sensor_r(end)]);
            end
            if app.ifScroll_2
                xlim(app.UIAxes2, [max(app.ts_sensor_r(end) - app.WindowSize, 0), app.ts_sensor_r(end)]);
%                 [~, y_data] = getpoints(app.AL_2);
%                 y_data_disp = y_data(max(end - (app.WindowSize * app.sampling_rate), 1) : end);
%                 ylim(app.UIAxes, [min(y_data_disp) * (1 - app.yPad), max(y_data_disp) * (1 + app.yPad)]);
            else
                [t, ~] = getpoints(app.AL_2);
                xlim(app.UIAxes2, [min(t), app.ts_sensor_r(end)]);
            end


            drawnow % plot now
        end

        function TerminateStream(app)

            % terminate the stream retrive session
            % enable start button
            app.StartButton.Enable = "on";

            % remove stream inlets
            app.inlet_sensor.delete();
            if app.markerOn % delete marker stream inlet only when it was initialized
                app.inlet_marker.delete();
            end
            % remove display values
            app.ts_sensor = -1;
            app.ts_marker = -1;
            app.ts_start = -1;

            app.data_vec = -1;
            app.data_vec_F = [];
            
            app.lean_angle = 0;
            app.lean_torque = 0;
            % clear UIAxes plots, animated lines
            clearpoints(app.AL_1);
            clearpoints(app.AL_2);
            cla(app.UIAxes);
            cla(app.UIAxes2);
            % remove stored values
            Clear_TT(app);

            % display log
            app.txt_log = ["Disconnected from the Stream Inlet."; "Stream Subscription and Stored Data Cleared."];
            app.TextArea2.Value = app.txt_log;
            app.txt_log = "";
            app.StartButton.Enable = "off";
            app.ifStop = false;
            
            % reset indicator
            app.ReadytoReleaseLamp.Color = 'w';
        end
       
        % called in a loop
        function Stream(app)     
            % get data from the inlet
            
            switch app.PullType
                case 'sample'
                    [app.data_vec, app.ts_sensor] = app.inlet_sensor.pull_sample(); 
                    
                case 'chunk'
                    [app.data_vec, app.ts_sensor] = app.inlet_sensor.pull_chunk();
                    
            end
            if app.markerOn
                [app.marker_flag, ts_m] = app.inlet_marker.pull_chunk();
                if ~isempty(ts_m)
                    app.ts_marker = ts_m;
                end
               
            end
            
            
            % test
%             data = app.data_vec;
%             ts = app.ts
            
            
            % data type conversion and update data to display box
            if ~isempty(app.ts_sensor)
                % if this is the first chunk of data during this stream
                % session, record the initial time as origin (for later plotting)
                if app.ts_start == -1
                    app.ts_start = app.ts_sensor(1);
                end
                % format data, including data vector type conversion and amplitude
                % conversion (based on resolution and ADC config)
                % time stamp conversion
                FormatData(app);
                
                
                if length(app.ts_sensor) == size(app.data_vec_F, 2) % check if the sizes of ts and data vector agree
                    % Channel selection
                    SelectChannel(app);

                    % Force and Angle calculation
                    CalculateForce(app);
                    CalculateAngle(app);
                    CalculateTorque(app);
                    % store raw data vectors and time stamps in time tables
                    Store_TT(app);
                    
                    % display
                    DisplayData(app);
                    % update indicator light
                    IndicatorLight(app);
                else
                    app.txt_log = "Mismatched Time Stamps and Data Vector Sizes!";
                    app.TextArea2.Value = app.txt_log;
                    app.ifStop = true; % terminates the stream subscription
                         
                end
            else
%                 app.RawReadingsTextArea.Value = 'no markers';
                
            end
            
            pause(0.1);
            
        end
        
        function IndicatorLight(app)
            switch app.EstimationType
                case 'Lean Angle'
                    value = app.lean_angle;
                    target_value = app.target_lean_angle;
                    
                case 'Lean Torque'
                    value = app.lean_torque;
                    target_value = app.target_lean_torque;
            end
            if value >= target_value + app.tolerance * target_value % if value is too large, outside of tolerance
                app.ReadytoReleaseLamp.Color = 'r';
            elseif value < target_value % if value is too small
                app.ReadytoReleaseLamp.Color = 'w';
            else % if value is sufficient and within tolerance.
                app.ReadytoReleaseLamp.Color = 'g';
            end
        end
        
        function Store_TT(app) % store data in time tables
            
            TT_new = table(app.ts_sensor', app.data_vec_F(app.Channel_num, :)', app.lean_angle(app.Channel_num, :)', app.lean_torque(app.Channel_num, :)');
            TT_marker_new = table(app.ts_marker');

            
            app.TT_all = [app.TT_all; TT_new];
            app.TT_marker = [app.TT_marker; TT_marker_new];
        end
        function Clear_TT(app) % clear TT
            app.TT_all = table(-1, 0, 0, 0);
            app.TT_marker = table(-1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % hold on
            hold(app.UIAxes, "on")
            hold(app.UIAxes2, "on")
        end

        % Value changed function: SensorPropTypeDropDown
        function SensorPropTypeDropDownValueChanged(app, event)
            app.PropType_sensor = app.SensorPropTypeDropDown.Value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            if isempty(app.EditField.Value)
                app.PropValue_sensor = app.EditField.Placeholder;
            else
                app.PropValue_sensor = app.EditField.Value;
            end
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            app.StartButton.Value = 0;
            app.ifStop = false;
            % if the button is pressed down, initiate stream inlets and
            % start printing out readings.
            if value == 1
                % create a new inlet
                app.txt_log = [app.txt_log; "Opening a sensor inlet..."]; % change log text
                app.TextArea2.Value = app.txt_log; % display log
                app.inlet_sensor = lsl_inlet(app.stream_infos_sensor{app.sensorStream_num});
                % check the type of the sensor stream
                app.stream_type_sensor = type(app.stream_infos_sensor{app.sensorStream_num});
                app.txt_log = [app.txt_log; strcat("Stream Type: ", app.stream_type_sensor)]; % change log text
                app.TextArea2.Value = app.txt_log; % display log
                if app.markerOn
                    app.txt_log = [app.txt_log; "Opening a marker inlet..."];
                    app.TextArea2.Value = app.txt_log; % display log
                    app.inlet_marker = lsl_inlet(app.stream_infos_marker{app.markerStream_num});
                end
                % start stream (disabling the start button)
                app.StartButton.Enable = "off";
                
                % initializing animated lines
                app.AL_1 = animatedline(app.UIAxes, "Color", "#0072BD");
                app.AL_2 = animatedline(app.UIAxes2, "Color", "#0072BD");
                % enable the calibrate button
                app.CalibrateButton.Enable = "on";

                while ~app.ifStop
                    app.Stream();
                end
                % terminate stream if the stop button is clicked
                
                app.TerminateStream();
                
            end


        end

        % Button pushed function: TerminateButton
        function TerminateButtonPushed(app, event)
            app.ifStop = true;
        end

        % Value changed function: EstimationTypeDropDown
        function EstimationTypeDropDownValueChanged(app, event)
            app.EstimationType = app.EstimationTypeDropDown.Value;

            % change UIAxes Layout and EditField Layout
            title(app.UIAxes2, ['Estimated ', app.EstimationType]);
            switch app.EstimationType
                case 'Lean Angle'
                    ylabel(app.UIAxes2, 'Angle (deg)');
                    ylim(app.UIAxes2, [0, 45]);
                    app.TargetAngledegEditFieldLabel.Text = 'Target Angle (deg)';
                case 'Lean Torque'
                    ylabel(app.UIAxes2, 'Torque (N \cdot m)');
%                     ylim(app.UIAxes2, [0, 300]);
                    app.TargetAngledegEditFieldLabel.Text = 'Target Torque (N*m)';
            end
        end

        % Button pushed function: InitiateButton
        function InitiateButtonPushed(app, event)
            
            % if the button is pressed down, initiate stream inlets and
            % start printing out readings.
            
            % instantiate the library
            app.txt_log = "Loading the library...";
            app.TextArea2.Value = app.txt_log; % display log
            app.lib = lsl_loadlib();
            % check sensor stream props and marker stream props
            if isempty(app.EditField.Value)
                app.PropValue_sensor = app.EditField.Placeholder;
            else
                app.PropValue_sensor = app.EditField.Value;
            end
            if isempty(app.EditField_3.Value)
                app.PropValue_marker = app.EditField_3.Placeholder;
            else
                app.PropValue_marker = app.EditField_3.Value; 
            end

            % resolve a sensor stream...
            sensor_prop_info = strcat("Sensor ", app.PropType_sensor, ": ", app.PropValue_sensor);
            app.txt_log = [app.txt_log; sensor_prop_info; "Resolving Sensor Streams..."];

            try
                app.stream_infos_sensor = lsl_resolve_byprop(app.lib, app.PropType_sensor, app.PropValue_sensor);
                
            catch
                app.txt_log = [app.txt_log; "==Warning! LSL cannot resolve by Property=="];
            end
            
            % check if there is a stream that satisfied the given
            % properties
            sz_streams_sensor = size(app.stream_infos_sensor);
            len_streams_sensor = length(app.stream_infos_sensor);
%             app.txt_log = [app.txt_log; num2str(sz_streams)];
            if sz_streams_sensor(1) * sz_streams_sensor(2) ~= 0
                % update info
                app.max_channel_count = channel_count(app.stream_infos_sensor{app.sensorStream_num}); % updating the max number of channels according to the stream info
                app.sampling_rate = nominal_srate(app.stream_infos_sensor{app.sensorStream_num}); % 
                app.txt_log = [app.txt_log; strcat(num2str(len_streams_sensor), " Stream(s) Found"); ...
                    strcat("Selected the Stream No.", num2str(app.sensorStream_num)); ...
                    strcat("    Name: ", name(app.stream_infos_sensor{app.sensorStream_num})); ...
                    strcat("    Type: ", type(app.stream_infos_sensor{app.sensorStream_num})); ...
                    strcat("    Number of Channels: ", num2str(app.max_channel_count)); ...
                    strcat("    Nominal Sampling Rate: ", num2str(app.sampling_rate), " Hz"); ...
                    strcat("    Source ID: ", source_id(app.stream_infos_sensor{app.sensorStream_num}))];
                app.StartButton.Enable = "on";
                
%                 disp(app.stream_infos_sensor)
            else
                app.txt_log = [app.txt_log; "No Stream Found"];
            end
            app.txt_log = [app.txt_log; " "];
            app.TextArea2.Value = app.txt_log;
           
            % resolve a marker stream...
            if app.markerOn
                marker_prop_info = strcat("Marker ", app.PropType_marker, ": ", app.PropValue_marker);
                app.txt_log = [app.txt_log; marker_prop_info; "Resolving Marker Streams..."];
                % if resolving a marker stream, need to wait longer before
                % retriving signal
                if isequal(app.PropValue_marker, 'Markers')
                    app.time_out = 30;
                    app.txt_log = [app.txt_log; strcat("Time out ", num2str(app.time_out),"s")];
                end
                app.TextArea2.Value = app.txt_log;

                try
                    app.stream_infos_marker = lsl_resolve_byprop(app.lib, app.PropType_marker, app.PropValue_marker);
                    
                catch
                    app.txt_log = [app.txt_log; "==Warning! LSL cannot resolve by Property=="];
                end

                % check if there is a stream that satisfied the given
                % properties
                sz_streams_marker = size(app.stream_infos_marker);
                len_streams_marker = length(app.stream_infos_marker);
    %             app.txt_log = [app.txt_log; num2str(sz_streams)];
                
                if (sz_streams_marker(1) * sz_streams_marker(2) ~= 0)
                    app.txt_log = [app.txt_log; strcat(num2str(len_streams_marker), " Stream(s) Found")];
                    app.StartButton.Enable = "on";
                    marker_stream_found = false;
                    for i = 1 : len_streams_marker
                        app.stream_type_marker = type(app.stream_infos_marker{i});
                        if isequal(app.stream_type_marker, 'Markers')
                            app.markerStream_num = i;
                            app.txt_log = [app.txt_log; strcat("Selected the stream No.", num2str(i)); ...
                                strcat("Name: ", name(app.stream_infos_marker{app.sensorStream_num}))];
                            marker_stream_found = true;
                            break
                        end
                    end
                    if ~marker_stream_found
                        app.txt_log = [app.txt_log; "No Stream(s) Found are Marker Streams"];
                        app.markerOn = false;
                        app.MarkerSwitch.Value = "Off";
                    end
                else
                    app.txt_log = [app.txt_log; "No Marker Stream Found"];
                    % change marker status
                    app.markerOn = false;
                    app.MarkerSwitch.Value = "Off";
                end
                app.txt_log = [app.txt_log; " "];
                app.TextArea2.Value = app.txt_log;
            end


        end

        % Value changed function: PullTypeDropDown
        function PullTypeDropDownValueChanged(app, event)
            app.PullType = app.PullTypeDropDown.Value;
            
        end

        % Value changed function: CoMTypeDropDown
        function CoMTypeDropDownValueChanged(app, event)
            app.CoMType = app.CoMTypeDropDown.Value;
            if isequal(app.CoMType, 'Custom')
                app.CoMCoefficientSpinner.Enable = "on";
            end
        end

        % Value changed function: WeightkgSpinner
        function WeightkgSpinnerValueChanged(app, event)
            app.mass = app.WeightkgSpinner.Value;
            
        end

        % Value changed function: HeightcmSpinner
        function HeightcmSpinnerValueChanged(app, event)
            app.patient_height = app.HeightcmSpinner.Value;
            
        end

        % Value changed function: CoMCoefficientSpinner
        function CoMCoefficientSpinnerValueChanged(app, event)
            switch app.CoMType
                case 'Custom'
                    app.CoMCoeff = app.CoMCoefficientSpinner.Value;
                case 'Male Avg'
                    app.CoMCoeff = 0.65;
                case 'Female Avg'
                    app.CoMCoeff = 0.60;
                otherwise
                    app.CoMCoeff = 0.65;
            end
            
        end

        % Value changed function: TetherHeightcmSpinner
        function TetherHeightcmSpinnerValueChanged(app, event)
            app.tether_height = app.TetherHeightcmSpinner.Value;
            
        end

        % Value changed function: TargetAngledegEditField
        function TargetValueEditFieldValueChanged(app, event)
            switch app.EstimationType
                case 'Lean Angle'
                    app.target_lean_angle = app.TargetAngledegEditField.Value;
                    yline_value = app.target_lean_angle;
                case 'Lean Torque'
                    app.target_lean_torque = app.TargetAngledegEditField.Value;
                    yline_value = app.target_lean_angle;
            end
            % Remove the old yline if it exists
            if ~isempty(app.T_yline) && isvalid(app.T_yline)
                delete(app.T_yline);  % Delete the previous yline object
            end

            app.T_yline = yline(app.UIAxes2, yline_value, '-r', strcat('Target ', app.EstimationType));
        end

        % Menu selected function: ScrollMenu
        function ScrollMenuSelected(app, event)
            app.ifScroll_1 = ~app.ifScroll_1;
        end

        % Menu selected function: ScrollMenu_2
        function ScrollMenu_2Selected(app, event)
            app.ifScroll_2 = ~app.ifScroll_2;
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            clearpoints(app.AL_1);
        end

        % Menu selected function: ClearMenu_2
        function ClearMenu_2Selected(app, event)
            clearpoints(app.AL_2);
        end

        % Value changed function: ChannelSelectEditField
        function ChannelSelectEditFieldValueChanged(app, event)
            app.Channel_num = app.ChannelSelectEditField.Value;           
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            current_time = datetime("now");
            current_time.Format = "yyyy_MM_dd_hh_mm_ss";
            % obtain file name and file path
            save_fileName_prefix = "Recording_sample_";
            if isempty(app.FileNameEditField.Value)
                save_fileName = strcat(save_fileName_prefix, string(current_time));
            else
                save_fileName = app.FileNameEditField.Value;
            end

            if isempty(app.SavePathEditField.Value)
                save_filePath = pwd; % use the current working folder
            else
                save_filePath = app.SavePathEditField.Value;
            end
            % obtain save format
            save_format = app.FileTypeDropDown.Value;
            save_fileName_wt_format = string(save_fileName) + string(save_format);
            
            save_fullPath = fullfile(save_filePath, save_fileName_wt_format);
            % save sensor data
            recorded_sample = app.TT_all;
            % set variable names
            recorded_sample.Properties.VariableNames = ["Time Stamps","Raw Formatted Data","Estimated Lean Angle","Estimated Lean Torque"];
            if ~isequal(save_format, '.mat')
                writetable(recorded_sample, save_fullPath, 'WriteVariableNames', true);
            else
                
                save(save_fullPath, "recorded_sample");
            end
            % save marker
            if app.markerOn
                % unique filename, same save path
                save_fileName = strcat(save_fileName, "_markers");
                save_fileName_wt_format = string(save_fileName) + string(save_format);
                save_fullPath = fullfile(save_filePath, save_fileName_wt_format);

                recorded_sample = app.TT_marker;
                % set variable names
                recorded_sample.Properties.VariableNames = "Time Stamps";
                if ~isequal(save_format, '.mat')
                    writetable(recorded_sample, save_fullPath, 'WriteVariableNames', true);
                else
                    
                    save(save_fullPath, "recorded_sample");
                end
            end
            app.txt_log = [app.txt_log; strcat("File Saved at ", save_fullPath)];
            
            app.TextArea2.Value = app.txt_log; % display log
            
            % clear tables
            Clear_TT(app);
        end

        % Value changed function: MarkerPropTypeDropDown
        function MarkerPropTypeDropDownValueChanged(app, event)
            app.PropType_marker = app.MarkerPropTypeDropDown.Value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            if isempty(app.EditField_3.Value)
                app.PropValue_marker = app.EditField_3.Placeholder;
            else
                app.PropValue_marker = app.EditField_3.Value; 
            end
        end

        % Value changed function: MarkerSwitch
        function MarkerSwitchValueChanged(app, event)
            app.txt_log = [app.txt_log; strcat("Marker Switch: ", string(app.MarkerSwitch.Value))];
            app.TextArea2.Value = app.txt_log; % display log
            % change marker Stream Status
            if isequal(app.MarkerSwitch.Value, "Off")
                app.markerOn = false;
            else
                app.markerOn = true;
            end
        end

        % Value changed function: RawReadingsTypeDropDown
        function RawReadingsTypeDropDownValueChanged(app, event)
            app.rawReadingsType = app.RawReadingsTypeDropDown.Value;

            % change UIAxes Layout and EditField Layout
            title(app.UIAxes, 'Raw Readings');
            switch app.rawReadingsType
                case 'N'
                    ylabel(app.UIAxes, 'Tension (N)');
%                     ylim(app.UIAxes, [0, 100]);
                case 'V'
                    ylabel(app.UIAxes, 'Voltage (V)');
%                     ylim(app.UIAxes, [0, 5]);
                case 'mV'
                    ylabel(app.UIAxes, 'Voltage (mV)');
%                     ylim(app.UIAxes, [0, 1000]);
                otherwise
                    ylabel(app.UIAxes, '%');
                    ylim(app.UIAxes, [0, 30]);
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            % the difference between the measured force and the calibration weight force should be the offset.
            app.offset = (app.CalibWeightkgEditField.Value * app.grav_acc) - app.force; 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {769, 769};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {490, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Control Panel';
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.PatientInfoLabel = uilabel(app.GridLayout2);
            app.PatientInfoLabel.Text = 'Patient Info';
            app.WeightkgSpinnerLabel = uilabel(app.GridLayout2);
            app.WeightkgSpinnerLabel.Text = 'Weight (kg)';
            app.WeightkgSpinner = uispinner(app.GridLayout2);
            app.WeightkgSpinner.Limits = [0 200];
            app.WeightkgSpinner.ValueChangedFcn = createCallbackFcn(app, @WeightkgSpinnerValueChanged, true);
            app.WeightkgSpinner.Value = 60;
            app.HeightcmSpinnerLabel = uilabel(app.GridLayout2);
            app.HeightcmSpinnerLabel.Text = 'Height (cm)';
            app.HeightcmSpinner = uispinner(app.GridLayout2);
            app.HeightcmSpinner.Limits = [0 250];
            app.HeightcmSpinner.ValueChangedFcn = createCallbackFcn(app, @HeightcmSpinnerValueChanged, true);
            app.HeightcmSpinner.Value = 170;
            app.LogLabel = uilabel(app.GridLayout2);
            app.LogLabel.Text = 'Log';
            app.TextArea2 = uitextarea(app.GridLayout2);
            app.TextArea2.Editable = 'off';
            app.SensorPropTypeDropDownLabel = uilabel(app.GridLayout2);
            app.SensorPropTypeDropDownLabel.Text = 'Sensor Prop Type';
            app.SensorPropTypeDropDown = uidropdown(app.GridLayout2);
            app.SensorPropTypeDropDown.Items = {'name', 'type', 'source_id'};
            app.SensorPropTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SensorPropTypeDropDownValueChanged, true);
            app.SensorPropTypeDropDown.Value = 'type';
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Placeholder = 'EEG';
            app.AuxiliaryInfoLabel = uilabel(app.GridLayout2);
            app.AuxiliaryInfoLabel.Text = 'Auxiliary Info';
            app.WeightkgSpinnerLabel_2 = uilabel(app.GridLayout2);
            app.WeightkgSpinnerLabel_2.Text = 'Sensor Prop Value';
            app.InitiateButton = uibutton(app.GridLayout2, 'push');
            app.InitiateButton.ButtonPushedFcn = createCallbackFcn(app, @InitiateButtonPushed, true);
            app.InitiateButton.Text = 'Initiate';
            app.StartButton = uibutton(app.GridLayout2, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.TerminateButton = uibutton(app.GridLayout2, 'push');
            app.TerminateButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateButtonPushed, true);
            app.TerminateButton.Text = 'Terminate';
            app.CoMCoefficientSpinnerLabel = uilabel(app.GridLayout2);
            app.CoMCoefficientSpinnerLabel.Enable = 'off';
            app.CoMCoefficientSpinnerLabel.Text = 'CoM Coefficient';
            app.CoMCoefficientSpinner = uispinner(app.GridLayout2);
            app.CoMCoefficientSpinner.ValueChangedFcn = createCallbackFcn(app, @CoMCoefficientSpinnerValueChanged, true);
            app.CoMCoefficientSpinner.Enable = 'off';
            app.TetherHeightcmSpinnerLabel = uilabel(app.GridLayout2);
            app.TetherHeightcmSpinnerLabel.Text = 'Tether Height (cm)';
            app.TetherHeightcmSpinner = uispinner(app.GridLayout2);
            app.TetherHeightcmSpinner.ValueChangedFcn = createCallbackFcn(app, @TetherHeightcmSpinnerValueChanged, true);
            app.TetherHeightcmSpinner.Value = 150;
            app.PullTypeDropDownLabel = uilabel(app.GridLayout2);
            app.PullTypeDropDownLabel.Text = 'Pull Type';
            app.PullTypeDropDown = uidropdown(app.GridLayout2);
            app.PullTypeDropDown.Items = {'chunk', 'sample'};
            app.PullTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PullTypeDropDownValueChanged, true);
            app.PullTypeDropDown.Value = 'chunk';
            app.CoMTypeDropDownLabel = uilabel(app.GridLayout2);
            app.CoMTypeDropDownLabel.Text = 'CoM Type';
            app.CoMTypeDropDown = uidropdown(app.GridLayout2);
            app.CoMTypeDropDown.Items = {'Male Avg', 'Female Avg', 'Custom'};
            app.CoMTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CoMTypeDropDownValueChanged, true);
            app.CoMTypeDropDown.Value = 'Male Avg';
            app.SensorStreamInfoLabel = uilabel(app.GridLayout2);
            app.SensorStreamInfoLabel.Text = 'Sensor Stream Info';
            app.CalibrateButton = uibutton(app.GridLayout2, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.StreamControlsLabel = uilabel(app.GridLayout2);
            app.StreamControlsLabel.Text = 'Stream Controls';
            app.CalibWeightkgEditFieldLabel = uilabel(app.GridLayout2);
            app.CalibWeightkgEditFieldLabel.Text = 'Calib Weight (kg)';
            app.CalibWeightkgEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MarkerStreamInfoLabel = uilabel(app.GridLayout2);
            app.MarkerStreamInfoLabel.Text = 'Marker Stream Info';
            app.MarkerPropTypeDropDownLabel = uilabel(app.GridLayout2);
            app.MarkerPropTypeDropDownLabel.Text = 'Marker Prop Type';
            app.MarkerPropTypeDropDown = uidropdown(app.GridLayout2);
            app.MarkerPropTypeDropDown.Items = {'name', 'type', 'source_id'};
            app.MarkerPropTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerPropTypeDropDownValueChanged, true);
            app.MarkerPropTypeDropDown.Value = 'name';
            app.EditField_3 = uieditfield(app.GridLayout2, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Placeholder = 'LiveAmpSN-102801-1200-STETriggerIn';
            app.WeightkgSpinnerLabel_3 = uilabel(app.GridLayout2);
            app.WeightkgSpinnerLabel_3.Text = 'Marker Prop Value';
            app.MarkerSwitchLabel = uilabel(app.GridLayout2);
            app.MarkerSwitchLabel.Text = 'Marker Switch';
            app.MarkerSwitch = uiswitch(app.GridLayout2, 'slider');
            app.MarkerSwitch.ValueChangedFcn = createCallbackFcn(app, @MarkerSwitchValueChanged, true);
            app.MarkerSwitch.Value = 'On';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Display Panel';
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Raw Readings')
            ylabel(app.UIAxes, '%')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 30];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 5 10 15 20 25 30];
            app.UIAxes.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'};
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'Estimated Lean Angle')
            xlabel(app.UIAxes2, 'time (s)')
            ylabel(app.UIAxes2, 'Angle (\theta)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YLim = [-Inf Inf];
            app.ReadytoReleaseLampLabel = uilabel(app.GridLayout3);
            app.ReadytoReleaseLampLabel.Text = 'Ready to Release';
            app.ReadytoReleaseLamp = uilamp(app.GridLayout3);
            app.RawReadingsTextAreaLabel = uilabel(app.GridLayout3);
            app.RawReadingsTextAreaLabel.Text = 'Raw Readings';
            app.RawReadingsTextArea = uitextarea(app.GridLayout3);
            app.RawReadingsTextArea.Editable = 'off';
            app.SaveButton = uibutton(app.GridLayout3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SavePathEditFieldLabel = uilabel(app.GridLayout3);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.GridLayout3, 'text');
            app.SavePathEditField.Placeholder = 'Working Folder';
            app.FileNameEditFieldLabel = uilabel(app.GridLayout3);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GridLayout3, 'text');
            app.FileNameEditField.Placeholder = 'Recording_sample_yyyy_MM_dd_hh_mm_ss';
            app.FileTypeDropDownLabel = uilabel(app.GridLayout3);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.GridLayout3);
            app.FileTypeDropDown.Items = {'.csv', '.mat', '.xlsx'};
            app.FileTypeDropDown.Value = '.mat';
            app.RawReadingsTypeDropDownLabel = uilabel(app.GridLayout3);
            app.RawReadingsTypeDropDownLabel.Text = 'Raw Readings Type';
            app.RawReadingsTypeDropDown = uidropdown(app.GridLayout3);
            app.RawReadingsTypeDropDown.Items = {'% Body Weight', 'mV', 'V', 'N'};
            app.RawReadingsTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RawReadingsTypeDropDownValueChanged, true);
            app.RawReadingsTypeDropDown.Value = '% Body Weight';
            app.EstimationTypeDropDownLabel = uilabel(app.GridLayout3);
            app.EstimationTypeDropDownLabel.Text = 'Estimation Type';
            app.EstimationTypeDropDown = uidropdown(app.GridLayout3);
            app.EstimationTypeDropDown.Items = {'Lean Angle', 'Lean Torque'};
            app.EstimationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EstimationTypeDropDownValueChanged, true);
            app.EstimationTypeDropDown.Value = 'Lean Angle';
            app.TargetAngledegEditFieldLabel = uilabel(app.GridLayout3);
            app.TargetAngledegEditFieldLabel.Text = 'Target Angle (deg)';
            app.TargetAngledegEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TargetAngledegEditField.Limits = [0 Inf];
            app.TargetAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @TargetValueEditFieldValueChanged, true);
            app.TargetAngledegEditField.Tooltip = {''};
            app.ChannelSelectEditFieldLabel = uilabel(app.GridLayout3);
            app.ChannelSelectEditFieldLabel.Text = 'Channel Select';
            app.ChannelSelectEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ChannelSelectEditField.Limits = [0 100];
            app.ChannelSelectEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectEditFieldValueChanged, true);
            app.ChannelSelectEditField.Value = 75;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ClearMenu = uimenu(app.ContextMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.ScrollMenu = uimenu(app.ContextMenu);
            app.ScrollMenu.MenuSelectedFcn = createCallbackFcn(app, @ScrollMenuSelected, true);
            app.ScrollMenu.Text = 'Scroll';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.ClearMenu_2 = uimenu(app.ContextMenu2);
            app.ClearMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ClearMenu_2Selected, true);
            app.ClearMenu_2.Text = 'Clear';
            app.ScrollMenu_2 = uimenu(app.ContextMenu2);
            app.ScrollMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ScrollMenu_2Selected, true);
            app.ScrollMenu_2.Text = 'Scroll';
            % Assign app.ContextMenu2
            app.UIAxes2.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tethered_Lean_Release_Feedback

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end